classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CommandTextAreaLabel        matlab.ui.control.Label
        CommandTextArea             matlab.ui.control.TextArea
        DegreeSliderLabel           matlab.ui.control.Label
        DegreeSlider                matlab.ui.control.Slider
        LinkDropDownLabel           matlab.ui.control.Label
        LinkDropDown                matlab.ui.control.DropDown
        CollisiondetectionTextAreaLabel  matlab.ui.control.Label
        CollisiondetectionTextArea  matlab.ui.control.TextArea
        EStopButton                 matlab.ui.control.Button
        ProcessEditFieldLabel       matlab.ui.control.Label
        ProcessEditField            matlab.ui.control.EditField
        ResumeButton                matlab.ui.control.Button
        PremiumPearlButton          matlab.ui.control.Button
        MatchaButton                matlab.ui.control.Button
        SpecialButton               matlab.ui.control.Button
        teachButton                 matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        DegreeEditFieldLabel        matlab.ui.control.Label
        DegreeEditField             matlab.ui.control.EditField
        SpinnerLabel                matlab.ui.control.Label
        Spinner                     matlab.ui.control.Spinner
    end


    properties (Access = private)
        Link;
        sBot=Sawyer(0,0,0,true);
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SelectButtonPushed(app, event)
            if app.Menu == 2
                text1 = sprintf('Second menu\n');
                % put funtion for second menu
            elseif app.Menu == 3
                text1 = sprintf('Third menu\n');
                % put funtion for third menu
            elseif app.Menu == 4
                text1 = sprintf('Forth menu\n');
                % put funtion for forth menu
            else
                text1 = sprintf('First menu\n');
                % put funtion for first menu
            end
            app.CommandTextArea.Value = text1;
        end

        % Callback function
        function MenuDropDownValueChanged(app, event)
            switch app.MenuDropDown.Value
                case 'Boba 1'
                    app.Menu=1;
                case 'Boba 2'
                    app.Menu=2;
                case 'Boba 3'
                    app.Menu=3;
                case 'Boba 4'
                    app.Menu=4;
            end
        end

        % Value changed function: CommandTextArea
        function CommandTextAreaValueChanged(app, event)
            value = app.CommandTextArea.Value;
        end

        % Value changed function: LinkDropDown
        function LinkDropDownValueChanged(app, event)
            
            switch app.LinkDropDown.Value
                case '1'
                    app.Link=1;
                    app.CommandTextArea.Value = sprintf('Link 1 is selected');
                    %InitRobot();
                    % link 1 code funcionapp.DegreeSlider
                case '2'
                    app.Link=2;
                    app.CommandTextArea.Value = sprintf('Link 2 is selected');
                    %InitRobot();
                    % link 2 code
                case '3'
                    app.Link=3;
                    app.CommandTextArea.Value = sprintf('Link 3 is selected');
                    %InitRobot();
                    % link 3 code
                case '4'
                    app.Link=4;
                    app.CommandTextArea.Value = sprintf('Link 4 is selected');
                    %InitRobot();
                    % link 4 code
                case '5'
                    app.Link=5;
                    app.CommandTextArea.Value = sprintf('Link 5 is selected');
                    %InitRobot();
                    % link 5 code
                case '6'
                    app.Link=6;
                    app.CommandTextArea.Value = sprintf('Link 6 is selected');
                    %InitRobot();
                    % link 6 code
                case '7'
                    app.Link=7;
                    app.CommandTextArea.Value = sprintf('Link 7 is selected');
                    %InitRobot();
                    % link 7 code
            end
        end

        % Value changing function: DegreeSlider
        function DegreeSliderValueChanging(app, event)
            changingValue = event.Value;
            Theta = app.DegreeSlider.Value;
            %app.DegreeEditFieldValueChanging.Value = num2str(Theta);
            RobotMove(app.Link,changingValue);
        end

        % Value changed function: CollisiondetectionTextArea
        function CollisiondetectionTextAreaValueChanged(app, event)
            value = app.CollisiondetectionTextArea.Value;
            
        end

        % Button pushed function: PremiumPearlButton
        function PremiumPearlButtonPushed(app, event)
            %app.Menu=1;
            app.CommandTextArea.Value = sprintf('Premium Pearl is selected\n');
            app.ProcessEditField.Value = sprintf('Order is done');
        end

        % Button pushed function: MatchaButton
        function MatchaButtonPushed(app, event)
            %app.PauseButtonapp.Menu=2;
            app.CommandTextArea.Value = sprintf('Matcha\n');
            app.ProcessEditField.Value = sprintf('In process');
            % if the order is done =
            % app.ProcessTextArea.Value = sprintf('order is done');
        end

        % Button pushed function: SpecialButton
        function SpecialButtonPushed(app, event)
            app.Menu=3;
            app.CommandTextArea.Value = sprintf('Special\n');
            app.ProcessEditField.Value = sprintf('In process');
        end

        % Value changed function: ProcessEditField
        function ProcessEditFieldValueChanged(app, event)
            value = app.ProcessEditField.Value;
            
        end

        % Callback function
        function Degree2EditFieldValueChanged(app, event)
            value = app.Degree2EditField.Value;
            
        end

        % Value changing function: DegreeEditField
        function DegreeEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
            
        end

        % Button pushed function: teachButton
        function teachButtonPushed(app, event)
            app.sBot= Sawyer(0,0,0,false);
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.CommandTextArea.Value = sprintf(x);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CommandTextAreaLabel = uilabel(app.UIFigure);
            app.CommandTextAreaLabel.Text = 'Command';
            app.CommandTextArea = uitextarea(app.UIFigure);
            app.CommandTextArea.ValueChangedFcn = createCallbackFcn(app, @CommandTextAreaValueChanged, true);
            app.CommandTextArea.Value = {'Please select the menu'};
            app.DegreeSliderLabel = uilabel(app.UIFigure);
            app.DegreeSliderLabel.Text = 'Degree';
            app.DegreeSlider = uislider(app.UIFigure);
            app.DegreeSlider.Limits = [0 360];
            app.DegreeSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200 220 240 260 280 300 320 340 360];
            app.DegreeSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '120', '140', '160', '180', '200', '220', '240', '260', '280', '300', '320', '340', '360'};
            app.DegreeSlider.ValueChangingFcn = createCallbackFcn(app, @DegreeSliderValueChanging, true);
            app.LinkDropDownLabel = uilabel(app.UIFigure);
            app.LinkDropDownLabel.Text = 'Link';
            app.LinkDropDown = uidropdown(app.UIFigure);
            app.LinkDropDown.Items = {'1', '2', '3', '4', '5', '6', '7'};
            app.LinkDropDown.ValueChangedFcn = createCallbackFcn(app, @LinkDropDownValueChanged, true);
            app.LinkDropDown.Value = '1';
            app.CollisiondetectionTextAreaLabel = uilabel(app.UIFigure);
            app.CollisiondetectionTextAreaLabel.Text = 'Collision detection';
            app.CollisiondetectionTextArea = uitextarea(app.UIFigure);
            app.CollisiondetectionTextArea.ValueChangedFcn = createCallbackFcn(app, @CollisiondetectionTextAreaValueChanged, true);
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.Text = 'E-Stop';
            app.ProcessEditFieldLabel = uilabel(app.UIFigure);
            app.ProcessEditFieldLabel.Text = 'Process';
            app.ProcessEditField = uieditfield(app.UIFigure, 'text');
            app.ProcessEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessEditFieldValueChanged, true);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.Text = 'Resume';
            app.PremiumPearlButton = uibutton(app.UIFigure, 'push');
            app.PremiumPearlButton.ButtonPushedFcn = createCallbackFcn(app, @PremiumPearlButtonPushed, true);
            app.PremiumPearlButton.Text = 'Premium Pearl';
            app.MatchaButton = uibutton(app.UIFigure, 'push');
            app.MatchaButton.ButtonPushedFcn = createCallbackFcn(app, @MatchaButtonPushed, true);
            app.MatchaButton.Text = 'Matcha';
            app.SpecialButton = uibutton(app.UIFigure, 'push');
            app.SpecialButton.ButtonPushedFcn = createCallbackFcn(app, @SpecialButtonPushed, true);
            app.SpecialButton.Text = 'Special :)';
            app.teachButton = uibutton(app.UIFigure, 'push');
            app.teachButton.ButtonPushedFcn = createCallbackFcn(app, @teachButtonPushed, true);
            app.teachButton.Text = '.teach';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.DegreeEditFieldLabel = uilabel(app.UIFigure);
            app.DegreeEditFieldLabel.Text = 'Degree';
            app.DegreeEditField = uieditfield(app.UIFigure, 'text');
            app.DegreeEditField.ValueChangingFcn = createCallbackFcn(app, @DegreeEditFieldValueChanging, true);
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end