classdef GUI3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MenuTab                  matlab.ui.container.Tab
        PremiumPearlButton       matlab.ui.control.Button
        MatchaButton             matlab.ui.control.Button
        JasminTeaButton          matlab.ui.control.Button
        CommandEditFieldLabel    matlab.ui.control.Label
        CommandEditField         matlab.ui.control.EditField
        ProgressEditFieldLabel   matlab.ui.control.Label
        ProgressEditField        matlab.ui.control.EditField
        EStopButton              matlab.ui.control.Button
        CollisionEditFieldLabel  matlab.ui.control.Label
        CollisionEditField       matlab.ui.control.EditField
        ResumeButton             matlab.ui.control.Button
        TeachTab                 matlab.ui.container.Tab
        q1SpinnerLabel           matlab.ui.control.Label
        q1Spinner                matlab.ui.control.Spinner
        q2SpinnerLabel           matlab.ui.control.Label
        q2Spinner                matlab.ui.control.Spinner
        q3SpinnerLabel           matlab.ui.control.Label
        q3Spinner                matlab.ui.control.Spinner
        q4SpinnerLabel           matlab.ui.control.Label
        q4Spinner                matlab.ui.control.Spinner
        q5SpinnerLabel           matlab.ui.control.Label
        q5Spinner                matlab.ui.control.Spinner
        q6SpinnerLabel           matlab.ui.control.Label
        q6Spinner                matlab.ui.control.Spinner
        q7SpinnerLabel           matlab.ui.control.Label
        q7Spinner                matlab.ui.control.Spinner
        ResetButton              matlab.ui.control.Button
        StartRMRCButton          matlab.ui.control.Button
        XSpinnerLabel            matlab.ui.control.Label
        XSpinner                 matlab.ui.control.Spinner
        YSpinnerLabel            matlab.ui.control.Label
        YSpinner                 matlab.ui.control.Spinner
        ZSpinnerLabel            matlab.ui.control.Label
        ZSpinner                 matlab.ui.control.Spinner
        ColissionCheckTab        matlab.ui.container.Tab
    end

    
    properties (Access = private)
        ModelSawyer;
        
        OldRad1 = 0;
        OldRad2 = 0;
        OldRad3 = 0;
        OldRad4 = 0;
        OldRad5 = 0;
        OldRad6 = 0;
        OldRad7 = 0;
        
        bottleObject;
        
        order1;
        order2;
        order3;
        
        q0 = [0 0 0 0 0 0 0]
        qReset;
        
        Token = 0;
        
        RMRC_x;
        RMRC_y;
        RMRC_z;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          m_env_setup
          app.ModelSawyer = sBot;
          app.bottleObject = Bottle;
    
          m_container_locations
          
          % premium pearl milk tea when clicked
          app.order1.name = 'Premium Pearl Milk tea';
          app.order1.containerLocations = [p1;p2]; 
            
          % Matcha when clciked
          app.order2.name = 'Matcha Green Tea';
          app.order2.containerLocations = [p3;p2]; 
            
          % soemthe else when clciked
          app.order3.name = 'Jasmine tea';
          app.order3.containerLocations = [p4;p3]; 
            
          % Set false by default
%           app.EStopButton.Enable = false;
%           app.ResumeButton.Enable = false;

        end

        % Button pushed function: PremiumPearlButton
        function PremiumPearlButtonPushed(app, event)
            % Set UI states
            app.CommandEditField.Value = sprintf('Premium Pearl is selected\n');
            app.ProgressEditField.Value = sprintf('Order is in Progress');
            app.PremiumPearlButton.Enable = false;
            app.MatchaButton.Enable = false;
            app.JasminTeaButton.Enable = false;
%             app.EStopButton.Enable = true;
%             app.ResumeButton.Enable = true;

            % Start order
            app.ModelSawyer.StartOrderTrajectory(app.bottleObject, app.order1);

            % Return UI states
            app.PremiumPearlButton.Enable = true;
            app.MatchaButton.Enable = true;
            app.JasminTeaButton.Enable = true;
%             app.EStopButton.Enable = false;
%             app.ResumeButton.Enable = false;
            app.CommandEditField.Value = sprintf('<3');
            app.ProgressEditField.Value = sprintf('Finished');
        end

        % Value changing function: CommandEditField
        function CommandEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: ProgressEditField
        function ProgressEditFieldValueChanging(app, event)
            changingValue = event.Value;
           
        end

        % Button pushed function: MatchaButton
        function MatchaButtonPushed(app, event)
            app.CommandEditField.Value = sprintf('Matcha is selected\n');
            app.ProgressEditField.Value = sprintf('Order is in Progress');
            app.PremiumPearlButton.Enable = false;
            app.MatchaButton.Enable = false;
            app.JasminTeaButton.Enable = false;
            app.EStopButton.Enable = true;
            app.ResumeButton.Enable = true;

            % Start order
            app.ModelSawyer.StartOrderTrajectory(app.bottleObject, app.order2);

            % Return UI states
            app.PremiumPearlButton.Enable = true;
            app.MatchaButton.Enable = true;
            app.JasminTeaButton.Enable = true;
%             app.EStopButton.Enable = false;
%             app.ResumeButton.Enable = false;
            app.CommandEditField.Value = sprintf('<3');
            app.ProgressEditField.Value = sprintf('Finished');
        end

        % Button pushed function: JasminTeaButton
        function JasminTeaButtonPushed(app, event)
            app.CommandEditField.Value = sprintf('Jasmine Tea is selected\n');
            app.ProgressEditField.Value = sprintf('Order is in Progress');
            app.PremiumPearlButton.Enable = false;
            app.MatchaButton.Enable = false;
            app.JasminTeaButton.Enable = false;
%             app.EStopButton.Enable = true;
%             app.ResumeButton.Enable = true;

            % Start order
            app.ModelSawyer.StartOrderTrajectory(app.bottleObject, app.order3);

            % Return UI states
            app.PremiumPearlButton.Enable = true;
            app.MatchaButton.Enable = true;
            app.JasminTeaButton.Enable = true;
%             app.EStopButton.Enable = false;
%             app.ResumeButton.Enable = false;
            app.CommandEditField.Value = sprintf('<3');
            app.ProgressEditField.Value = sprintf('Finished');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
%             app.ModelSawyer = Sawyer(0,0,1, false);
            app.ModelSawyer.AnimateTrajectory(app.q0);
            app.OldRad1 = 0;
            app.q1Spinner.Value = 0;
            app.OldRad2 = 0;
            app.q2Spinner.Value = 0;
            app.OldRad3 = 0;
            app.q3Spinner.Value = 0;
            app.OldRad4 = 0;
            app.q4Spinner.Value = 0;
            app.OldRad5 = 0;
            app.q5Spinner.Value = 0;
            app.OldRad6 = 0;
            app.q6Spinner.Value = 0;
            app.OldRad7 = 0;
            app.q7Spinner.Value = 0;
        end

        % Value changing function: q1Spinner
        function q1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            link = 1;
            DegtoRad = changingValue*pi/180;
            NewRad = DegtoRad -app.OldRad1;
            app.OldRad1 = DegtoRad;
            app.ModelSawyer.SawyerMove(link,NewRad);
        end

        % Value changing function: q2Spinner
        function q2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            link = 2;
            DegtoRad = changingValue*pi/180;
            NewRad = DegtoRad -app.OldRad2;
            app.OldRad2 = DegtoRad;
            app.ModelSawyer.SawyerMove(link,NewRad);
        end

        % Value changing function: q3Spinner
        function q3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            link = 3;
            DegtoRad = changingValue*pi/180;
            NewRad = DegtoRad -app.OldRad3;
            app.OldRad3 = DegtoRad;
            app.ModelSawyer.SawyerMove(link,NewRad);
        end

        % Value changing function: q4Spinner
        function q4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            link = 4;
            DegtoRad = changingValue*pi/180;
            NewRad = DegtoRad -app.OldRad4;
            app.OldRad4 = DegtoRad;
            app.ModelSawyer.SawyerMove(link,NewRad);
        end

        % Value changing function: q5Spinner
        function q5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            link = 5;
            DegtoRad = changingValue*pi/180;
            NewRad = DegtoRad -app.OldRad5;
            app.OldRad5 = DegtoRad;
            app.ModelSawyer.SawyerMove(link,NewRad);
        end

        % Value changing function: q6Spinner
        function q6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            link = 6;
            DegtoRad = changingValue*pi/180;
            NewRad = DegtoRad -app.OldRad6;
            app.OldRad6 = DegtoRad;
            app.ModelSawyer.SawyerMove(link,NewRad);
        end

        % Value changing function: q7Spinner
        function q7SpinnerValueChanging(app, event)
            changingValue = event.Value;
            link = 7;
            DegtoRad = changingValue*pi/180;
            NewRad = DegtoRad -app.OldRad7;
            app.OldRad7 = DegtoRad;
            app.ModelSawyer.SawyerMove(link,NewRad);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
                q = app.ModelSawyer.model.getpos();
                app.OldRad1 = q(1);
                app.OldRad2 = q(2);
                app.OldRad3 = q(3);
                app.OldRad4 = q(4);
                app.OldRad5 = q(5);
                app.OldRad6 = q(6);
                app.OldRad7 = q(7);
    
                app.q1Spinner.Value = app.OldRad1 * 180/pi;
                app.q2Spinner.Value = app.OldRad2 * 180/pi;
                app.q3Spinner.Value = app.OldRad3 * 180/pi;
                app.q4Spinner.Value = app.OldRad4 * 180/pi;
                app.q5Spinner.Value = app.OldRad5 * 180/pi;
                app.q6Spinner.Value = app.OldRad6 * 180/pi;
                app.q7Spinner.Value = app.OldRad7 * 180/pi;

        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
              app.ModelSawyer.canOperate = false;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
              app.ModelSawyer.canOperate = true;
              app.ModelSawyer.AnimateTrajectoryWObject(app.ModelSawyer.qMResume, app.bottleObject, app.ModelSawyer.idxResume)
              app.ModelSawyer.DropOffPayload(app.bottleObject)
        end

        % Button pushed function: StartRMRCButton
        function StartRMRCButtonPushed(app, event)
                app.RMRC_x = 0;
                app.XSpinner.Value = app.RMRC_x;
                app.RMRC_y = 2.5;
                app.YSpinner.Value = app.RMRC_y;
                app.RMRC_z = 1; 
                app.ZSpinner.Value = app.RMRC_z;
                qCurrent = app.ModelSawyer.model.getpos();
                qM1 = InterpolateWaypointsRadians([qCurrent;    0.8129   -2.2497   -0.5871    0.7631   -1.2551    1.3699    0.5663], app.ModelSawyer.rStep);
                app.ModelSawyer.AnimateTrajectory(qM1);
        end

        % Callback function
        function Button2Pushed(app, event)
%                   NewY = app.RMRC_y+0.01;
%                   app.RMRC_y = NewY;
%                   qM2 = app.ModelSawyer.GenTrajRMRC(app.ModelSawyer.model.getpos(),[app.RMRC_x, app.RMRC_y, app.RMRC_z]');
%                   app.ModelSawyer.AnimateTrajectory(qM2);
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.RMRC_x = changingValue;
            qM2 = app.ModelSawyer.GenTrajRMRC(app.ModelSawyer.model.getpos(),[app.RMRC_x, app.RMRC_y, app.RMRC_z]');
            app.ModelSawyer.AnimateTrajectory(qM2);
        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.RMRC_y = changingValue;
            qM2 = app.ModelSawyer.GenTrajRMRC(app.ModelSawyer.model.getpos(),[app.RMRC_x, app.RMRC_y, app.RMRC_z]');
            app.ModelSawyer.AnimateTrajectory(qM2);
        end

        % Value changing function: ZSpinner
        function ZSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.RMRC_z = changingValue;
            qM2 = app.ModelSawyer.GenTrajRMRC(app.ModelSawyer.model.getpos(),[app.RMRC_x, app.RMRC_y, app.RMRC_z]');
            app.ModelSawyer.AnimateTrajectory(qM2);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.PremiumPearlButton = uibutton(app.MenuTab, 'push');
            app.PremiumPearlButton.ButtonPushedFcn = createCallbackFcn(app, @PremiumPearlButtonPushed, true);
            app.PremiumPearlButton.Text = 'Premium Pearl';
            app.MatchaButton = uibutton(app.MenuTab, 'push');
            app.MatchaButton.ButtonPushedFcn = createCallbackFcn(app, @MatchaButtonPushed, true);
            app.MatchaButton.Text = 'Matcha';
            app.JasminTeaButton = uibutton(app.MenuTab, 'push');
            app.JasminTeaButton.ButtonPushedFcn = createCallbackFcn(app, @JasminTeaButtonPushed, true);
            app.JasminTeaButton.Text = 'Jasmin Tea';
            app.CommandEditFieldLabel = uilabel(app.MenuTab);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.MenuTab, 'text');
            app.CommandEditField.ValueChangingFcn = createCallbackFcn(app, @CommandEditFieldValueChanging, true);
            app.ProgressEditFieldLabel = uilabel(app.MenuTab);
            app.ProgressEditFieldLabel.Text = 'Progress';
            app.ProgressEditField = uieditfield(app.MenuTab, 'text');
            app.ProgressEditField.ValueChangingFcn = createCallbackFcn(app, @ProgressEditFieldValueChanging, true);
            app.EStopButton = uibutton(app.MenuTab, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.CollisionEditFieldLabel = uilabel(app.MenuTab);
            app.CollisionEditFieldLabel.Text = 'Collision';
            app.CollisionEditField = uieditfield(app.MenuTab, 'text');
            app.ResumeButton = uibutton(app.MenuTab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.TeachTab = uitab(app.TabGroup);
            app.TeachTab.Title = 'Teach';
            app.q1SpinnerLabel = uilabel(app.TeachTab);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.TeachTab);
            app.q1Spinner.ValueChangingFcn = createCallbackFcn(app, @q1SpinnerValueChanging, true);
            app.q1Spinner.Limits = [-175 175];
            app.q2SpinnerLabel = uilabel(app.TeachTab);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.TeachTab);
            app.q2Spinner.ValueChangingFcn = createCallbackFcn(app, @q2SpinnerValueChanging, true);
            app.q2Spinner.Limits = [-175 175];
            app.q3SpinnerLabel = uilabel(app.TeachTab);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.TeachTab);
            app.q3Spinner.ValueChangingFcn = createCallbackFcn(app, @q3SpinnerValueChanging, true);
            app.q3Spinner.Limits = [-175 175];
            app.q4SpinnerLabel = uilabel(app.TeachTab);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.TeachTab);
            app.q4Spinner.ValueChangingFcn = createCallbackFcn(app, @q4SpinnerValueChanging, true);
            app.q4Spinner.Limits = [-170 170];
            app.q5SpinnerLabel = uilabel(app.TeachTab);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.TeachTab);
            app.q5Spinner.ValueChangingFcn = createCallbackFcn(app, @q5SpinnerValueChanging, true);
            app.q5Spinner.Limits = [-170 170];
            app.q6SpinnerLabel = uilabel(app.TeachTab);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.TeachTab);
            app.q6Spinner.ValueChangingFcn = createCallbackFcn(app, @q6SpinnerValueChanging, true);
            app.q6Spinner.Limits = [-170 170];
            app.q7SpinnerLabel = uilabel(app.TeachTab);
            app.q7SpinnerLabel.Text = 'q7';
            app.q7Spinner = uispinner(app.TeachTab);
            app.q7Spinner.ValueChangingFcn = createCallbackFcn(app, @q7SpinnerValueChanging, true);
            app.q7Spinner.Limits = [-270 270];
            app.ResetButton = uibutton(app.TeachTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StartRMRCButton = uibutton(app.TeachTab, 'push');
            app.StartRMRCButton.ButtonPushedFcn = createCallbackFcn(app, @StartRMRCButtonPushed, true);
            app.StartRMRCButton.Text = 'Start RMRC';
            app.XSpinnerLabel = uilabel(app.TeachTab);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.TeachTab);
            app.XSpinner.Step = 0.01;
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.XSpinner.Limits = [-0.5 0.5];
            app.YSpinnerLabel = uilabel(app.TeachTab);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.TeachTab);
            app.YSpinner.Step = 0.01;
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @YSpinnerValueChanging, true);
            app.YSpinner.Limits = [2 3];
            app.YSpinner.Value = 2.5;
            app.ZSpinnerLabel = uilabel(app.TeachTab);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.TeachTab);
            app.ZSpinner.Step = 0.01;
            app.ZSpinner.ValueChangingFcn = createCallbackFcn(app, @ZSpinnerValueChanging, true);
            app.ZSpinner.Limits = [0.5 1.5];
            app.ZSpinner.Value = 1;
            app.ColissionCheckTab = uitab(app.TabGroup);
            app.ColissionCheckTab.Title = 'Colission Check';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end