classdef GUIRaymondExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        OrderTab            matlab.ui.container.Tab
        MatchButton         matlab.ui.control.Button
        PremiumPearlButton  matlab.ui.control.Button
        TeachTab            matlab.ui.container.Tab
    end

    properties (Access = private)
        sawyer
        bottleObject
        % Container Locations
        order1
        % Matcha when clciked
        order2
        % Jasmine else when clciked
        order3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          % Set values
          m_env_setup
          app.sawyer = sBot;
          app.bottleObject = Bottle;
    
          m_container_locations
          app.order1.name = 'Premium Pearl Milk tea';
          app.order1.containerLocations = [p1;p2]; 
            
          % Matcha when clciked
          app.order2.name = 'Matcha Green Tea';
          app.order2.containerLocations = [p3;p2]; 
            
          % soemthe else when clciked
          app.order3.name = 'Jasmine tea';
          app.order3.containerLocations = [p4;p3]; 

          app.PremiumPearlButton.Enable = true;
          app.MatchButton.Enable = true;
        end

        % Button pushed function: PremiumPearlButton
        function PremiumPearlButtonPushed(app, event)
            app.sawyer.StartOrderTrajectory(app.bottleObject, app.order1)
        end

        % Button pushed function: MatchButton
        function MatchButtonPushed(app, event)
            app.sawyer.StartOrderTrajectory(app.bottleObject, app.order2)         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OrderTab = uitab(app.TabGroup);
            app.OrderTab.Title = 'Order';
            app.MatchButton = uibutton(app.OrderTab, 'push');
            app.MatchButton.ButtonPushedFcn = createCallbackFcn(app, @MatchButtonPushed, true);
            app.MatchButton.Enable = 'off';
            app.MatchButton.Text = 'Match';
            app.PremiumPearlButton = uibutton(app.OrderTab, 'push');
            app.PremiumPearlButton.ButtonPushedFcn = createCallbackFcn(app, @PremiumPearlButtonPushed, true);
            app.PremiumPearlButton.Enable = 'off';
            app.PremiumPearlButton.Text = 'Premium Pearl';
            app.TeachTab = uitab(app.TabGroup);
            app.TeachTab.Title = 'Teach';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIRaymondExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end